classdef app_eeg_artefacto_cardiaco_andres_duran_del_monte < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GuardarresultadosButton  matlab.ui.control.Button
        ProcesarButton           matlab.ui.control.Button
        CanalEEGDropDown         matlab.ui.control.DropDown
        CanalEEGDropDownLabel    matlab.ui.control.Label
        CargararchivoEDFButton   matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        TT
        eeg_original
        eeg_limpia
        artefacto
        frec
        canal
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargararchivoEDFButton
        function CargararchivoEDFButtonPushed(app, event)
            [archivo,path] = uigetfile('*.edf', 'Seleccionar archivo EDF'); 

            if isequal(archivo,0)
                return;
            end

            nombre = fullfile(path,archivo);

            app.TT = edfread(nombre);

            info = edfinfo(nombre);
            app.frec = info.NumSamples(1);
            
            if app.frec ~= 1024
                uialert(app.UIFigure, 'Los parámetros empleados por esta herramienta han sido optimizados para señales EEG con una frecuencia de muestreo de 1024 Hz', 'Aviso');
            end

            disp(class(app.TT))

            disp(app.TT)

            canales = app.TT.Properties.VariableNames;

            canales(strcmpi(canales, 'ECG')) = [];

            app.CanalEEGDropDown.Items = canales;

            disp(canales)


        end

        % Button pushed function: ProcesarButton
        function ProcesarButtonPushed(app, event)
            canal = app.CanalEEGDropDown.Value;
            eeg = vertcat(app.TT.(canal){:});

            canales = app.TT.Properties.VariableNames;

            hay_ecg = any(strcmpi(canales, 'ECG'));


            % Frecuencia de muestreo

            frec = app.frec;

            % Preprocesamiento EEG

            eeg = eeg - mean(eeg,1);
            [b_eeg, a_eeg] = butter(4, [0.5 45] / (frec/2), 'bandpass');
            eeg_nueva_filt = filtfilt(b_eeg, a_eeg, eeg);

            tiempo = (0:length(eeg_nueva_filt) -1)/frec;
       

            if hay_ecg

                ecg = vertcat(app.TT.ECG{:});

            % Preprocesamiento EEG

            % Preprocesamiento ECG

            [b_nueva, a_nueva] = butter(4, [0.8 20] / (frec/2), 'bandpass');
            card_ref_nueva = filtfilt(b_nueva, a_nueva, ecg);
            card_ref_nueva = (card_ref_nueva - mean(card_ref_nueva)) / std(card_ref_nueva);


            plot(app.UIAxes,tiempo(1:10*frec), eeg_nueva_filt(1:10*frec))
            title(app.UIAxes, ['EEG preprocesada - ' canal])
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud (\muV)')
            grid(app.UIAxes, 'on')

            plot(app.UIAxes_2,tiempo(1:10*frec), card_ref_nueva(1:10*frec))
            title(app.UIAxes_2, ['ECG preprocesada'])
            xlabel(app.UIAxes_2, 'Tiempo (s)')
            ylabel(app.UIAxes_2, 'Amplitud')
            grid(app.UIAxes_2, 'on')

            % WPD + Proyección Ortogonal

            wname = 'db6';
            level = 4;
            nodos_seleccionados = [15];

            T_eeg = wpdec(eeg_nueva_filt, level, wname);
            T_ecg = wpdec(card_ref_nueva, level, wname);

            for i = 1:length(nodos_seleccionados)

                nodo = nodos_seleccionados(i);

                f = read(T_eeg, 'data', nodo);
                v = read(T_ecg, 'data', nodo);

                tam_f = size(f);

                f_col = f(:);
                v_col = v(:);

                if length(f_col) ~= length(v_col)
                    continue
                end

                f_mean = mean(f_col);

                f0 = f_col - mean(f_col);
                v0 = v_col - mean(v_col);

                den = dot(v0, v0);

                if den < eps
                    continue
                end

                proy_col = (dot(f0, v0) / den) * v0;

                f_new_col = f0 - proy_col + f_mean;

                f_new = reshape(f_new_col, tam_f);

                T_eeg = write(T_eeg, 'data', nodo, f_new);

            end
            

            else 

                plot(app.UIAxes, tiempo(1:10*frec), eeg_nueva_filt(1:10*frec))
                title(app.UIAxes, ['EEG preprocesada - ' canal])
                xlabel(app.UIAxes, 'Tiempo (s)')
                ylabel(app.UIAxes, 'Amplitud (\muV)')
                grid(app.UIAxes, 'on')

                cla(app.UIAxes_2)
                title(app.UIAxes_2, 'ECG no disponible')
                axis(app.UIAxes_2, 'off')
                xlabel(app.UIAxes, 'Tiempo (s)')
                ylabel(app.UIAxes, 'Amplitud (\muV)')

            wname = 'db6';
            level = 4;
            nodos_seleccionados = [15];
            alpha = 0.5;

            T_eeg = wpdec(eeg_nueva_filt, level, wname);

            for i = 1:length(nodos_seleccionados)

                nodo = nodos_seleccionados(i);

                f = read(T_eeg, 'data', nodo);
                f_new = alpha*f;

                T_eeg = write(T_eeg, 'data', nodo, f_new);
                

            end
            end


            eeg_limpia = wprec(T_eeg);
            eeg_limpia = eeg_limpia(1:length(eeg));

            artefacto = eeg_nueva_filt - eeg_limpia;


            plot(app.UIAxes_3,tiempo(1:10*frec), eeg_limpia(1:10*frec))
            title(app.UIAxes_3, ['EEG procesada - ' canal])
            xlabel(app.UIAxes_3, 'Tiempo (s)')
            ylabel(app.UIAxes_3, 'Amplitud (\muV)')
            grid(app.UIAxes_3, 'on')

            plot(app.UIAxes_4,tiempo(1:10*frec), artefacto(1:10*frec))
            title(app.UIAxes_4, ['Artefacto eliminado'])
            xlabel(app.UIAxes_4, 'Tiempo (s)')
            ylabel(app.UIAxes_4, 'Amplitud (\muV)')
            grid(app.UIAxes_4, 'on')

            % Se guardan los resultados

            app.eeg_original = eeg_nueva_filt;
            app.eeg_limpia = eeg_limpia;
            app.artefacto = artefacto;
            app.canal = canal;


        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
            [archivo, path] = uiputfile({'*.xslx'}, 'Guardar resultados');

            if isequal(archivo, 0)
                return;
            end

            nombre = fullfile(path, archivo);

            t = (0:length(app.eeg_limpia) - 1)'/app.frec;

            tabla = table(t, app.eeg_original(:), app.eeg_limpia(:), app.artefacto(:), 'VariableNames',{'Tiempo (s)', 'EEG Original', 'EEg limpia', 'Artefacto cardiaco eliminado'});

            [~,~,ext] = fileparts(nombre);

            if strcmpi(ext,'.xslx')
                disp(nombre);
                writetable(tabla, 'resultado.xlsx');
            end

            uialert(app.UIFigure,'Resultados exportados correctamente.','Exportación completada');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {560, 560};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {215, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CargararchivoEDFButton = uibutton(app.LeftPanel, 'push');
            app.CargararchivoEDFButton.ButtonPushedFcn = createCallbackFcn(app, @CargararchivoEDFButtonPushed, true);
            app.CargararchivoEDFButton.Text = 'Cargar archivo EDF';
            app.CanalEEGDropDownLabel = uilabel(app.LeftPanel);
            app.CanalEEGDropDownLabel.Text = 'Canal EEG';
            app.CanalEEGDropDown = uidropdown(app.LeftPanel);
            app.ProcesarButton = uibutton(app.LeftPanel, 'push');
            app.ProcesarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarButtonPushed, true);
            app.ProcesarButton.Text = 'Procesar';
            app.GuardarresultadosButton = uibutton(app.LeftPanel, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_eeg_artefacto_cardiaco_andres_duran_del_monte

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end